classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ActionLabel                     matlab.ui.control.Label
        AksiDropDown                    matlab.ui.control.DropDown
        AksiDropDownLabel               matlab.ui.control.Label
        SpPanel                         matlab.ui.container.Panel
        SpFileCitraRefEditField         matlab.ui.control.EditField
        FileCitraRefLabel               matlab.ui.control.Label
        SpFileCitraInputEditField       matlab.ui.control.EditField
        FileCitraInputEditFieldLabel_4  matlab.ui.control.Label
        SpJalankanButton                matlab.ui.control.Button
        EqPanel                         matlab.ui.container.Panel
        EqFileCitraInputEditField       matlab.ui.control.EditField
        FileCitraInputEditFieldLabel_3  matlab.ui.control.Label
        EqJalankanButton                matlab.ui.control.Button
        FixPanel                        matlab.ui.container.Panel
        FixFileCitraInputEditField      matlab.ui.control.EditField
        FileCitraInputEditFieldLabel_2  matlab.ui.control.Label
        FixJalankanButton               matlab.ui.control.Button
        VizPanel                        matlab.ui.container.Panel
        VizFileCitraInputEditField      matlab.ui.control.EditField
        FileCitraInputEditFieldLabel    matlab.ui.control.Label
        VizJalankanButton               matlab.ui.control.Button
        AddinMunawwarYusuf13521085Label  matlab.ui.control.Label
        LouisCaesaKesuma13521069Label   matlab.ui.control.Label
        IF4073PemrosesanCitraDigitalLabel  matlab.ui.control.Label
        ImageProcessingAppLabel         matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: AksiDropDown
        function AksiDropDownValueChanged(app, event)
            value = app.AksiDropDown.Value;
            app.VizPanel.Visible = 'off';
            app.FixPanel.Visible = 'off';
            app.EqPanel.Visible = 'off';
            app.SpPanel.Visible = 'off';

            app.ActionLabel.Text = value;

            switch value
                case 'Tampilkan Histogram'
                    app.VizPanel.Visible = 'on';
                case 'Perbaikan Citra'
                    app.FixPanel.Visible = 'on';
                case 'Perataan Histogram'
                    app.EqPanel.Visible = 'on';
                case 'Spesifikasi Histogram'
                    app.SpPanel.Visible = 'on';
            end
        end

        % Button pushed function: VizJalankanButton
        function VizJalankanButtonPushed(app, event)
            file = app.VizFileCitraInputEditField.Value;
            basepath = '../images/';
            img = imread(strcat(basepath, file));
            HistogramViz.ShowHist(img, 'Input');
        end

        % Button pushed function: FixJalankanButton
        function FixJalankanButtonPushed(app, event)
            file = app.FixFileCitraInputEditField.Value;
            basepath = '../images/';
            img = imread(strcat(basepath, file));
            % Image fix
            a = 1.2;
            b = 50;
            c = 2;
            gamma = 0.5;

            ImageFix.runAll(img, a, b, c, gamma)
        end

        % Button pushed function: EqJalankanButton
        function EqJalankanButtonPushed(app, event)
            file = app.EqFileCitraInputEditField.Value;
            basepath = '../images/';
            img = imread(strcat(basepath, file));
            % Histogram Equalization
            HistogramViz.ShowHist(img, 'Input');
            result = HistogramEq.HistEq(img);
            figure;
            imshow(result);
            title('Citra Hasil Perataan Histogram');
            HistogramViz.ShowHist(result, 'Output');  
        end

        % Button pushed function: SpJalankanButton
        function SpJalankanButtonPushed(app, event)
            file = app.SpFileCitraInputEditField.Value;
            basepath = '../images/';
            img = imread(strcat(basepath, file));
            
            fileRef = app.SpFileCitraRefEditField.Value;
            imgRef = imread(strcat(basepath, fileRef));
            
            HistogramSpec.specify(img, imgRef);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImageProcessingAppLabel = uilabel(app.UIFigure);
            app.ImageProcessingAppLabel.Text = 'Image Processing App';
            app.IF4073PemrosesanCitraDigitalLabel = uilabel(app.UIFigure);
            app.IF4073PemrosesanCitraDigitalLabel.Text = 'IF4073 Pemrosesan Citra Digital ';
            app.LouisCaesaKesuma13521069Label = uilabel(app.UIFigure);
            app.LouisCaesaKesuma13521069Label.Text = 'Louis Caesa Kesuma - 13521069';
            app.AddinMunawwarYusuf13521085Label = uilabel(app.UIFigure);
            app.AddinMunawwarYusuf13521085Label.Text = 'Addin Munawwar Yusuf - 13521085';
            app.VizPanel = uipanel(app.UIFigure);
            app.VizJalankanButton = uibutton(app.VizPanel, 'push');
            app.VizJalankanButton.ButtonPushedFcn = createCallbackFcn(app, @VizJalankanButtonPushed, true);
            app.VizJalankanButton.Text = 'Jalankan';
            app.FileCitraInputEditFieldLabel = uilabel(app.VizPanel);
            app.FileCitraInputEditFieldLabel.Text = 'File Citra Input';
            app.VizFileCitraInputEditField = uieditfield(app.VizPanel, 'text');
            app.FixPanel = uipanel(app.UIFigure);
            app.FixPanel.Visible = 'off';
            app.FixJalankanButton = uibutton(app.FixPanel, 'push');
            app.FixJalankanButton.ButtonPushedFcn = createCallbackFcn(app, @FixJalankanButtonPushed, true);
            app.FixJalankanButton.Text = 'Jalankan';
            app.FileCitraInputEditFieldLabel_2 = uilabel(app.FixPanel);
            app.FileCitraInputEditFieldLabel_2.Text = 'File Citra Input';
            app.FixFileCitraInputEditField = uieditfield(app.FixPanel, 'text');
            app.EqPanel = uipanel(app.UIFigure);
            app.EqPanel.Visible = 'off';
            app.EqJalankanButton = uibutton(app.EqPanel, 'push');
            app.EqJalankanButton.ButtonPushedFcn = createCallbackFcn(app, @EqJalankanButtonPushed, true);
            app.EqJalankanButton.Text = 'Jalankan';
            app.FileCitraInputEditFieldLabel_3 = uilabel(app.EqPanel);
            app.FileCitraInputEditFieldLabel_3.Text = 'File Citra Input';
            app.EqFileCitraInputEditField = uieditfield(app.EqPanel, 'text');
            app.SpPanel = uipanel(app.UIFigure);
            app.SpPanel.Visible = 'off';
            app.SpJalankanButton = uibutton(app.SpPanel, 'push');
            app.SpJalankanButton.ButtonPushedFcn = createCallbackFcn(app, @SpJalankanButtonPushed, true);
            app.SpJalankanButton.Text = 'Jalankan';
            app.FileCitraInputEditFieldLabel_4 = uilabel(app.SpPanel);
            app.FileCitraInputEditFieldLabel_4.Text = 'File Citra Input';
            app.SpFileCitraInputEditField = uieditfield(app.SpPanel, 'text');
            app.FileCitraRefLabel = uilabel(app.SpPanel);
            app.FileCitraRefLabel.Text = 'File Citra Ref';
            app.SpFileCitraRefEditField = uieditfield(app.SpPanel, 'text');
            app.AksiDropDownLabel = uilabel(app.UIFigure);
            app.AksiDropDownLabel.Text = 'Aksi';
            app.AksiDropDown = uidropdown(app.UIFigure);
            app.AksiDropDown.Items = {'Tampilkan Histogram', 'Perbaikan Citra', 'Perataan Histogram', 'Spesifikasi Histogram'};
            app.AksiDropDown.ValueChangedFcn = createCallbackFcn(app, @AksiDropDownValueChanged, true);
            app.AksiDropDown.Value = 'Tampilkan Histogram';
            app.ActionLabel = uilabel(app.UIFigure);
            app.ActionLabel.Text = 'Tampilkan Histogram';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end